classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        Image                        matlab.ui.control.Image
        SelectyourwavfileLabel       matlab.ui.control.Label
        BrowseButton                 matlab.ui.control.Button
        SelectyourFilterButtonGroup  matlab.ui.container.ButtonGroup
        BandPassButton               matlab.ui.control.RadioButton
        HighPassButton               matlab.ui.control.RadioButton
        LowPassButton                matlab.ui.control.RadioButton
        SelectyourWindowButtonGroup  matlab.ui.container.ButtonGroup
        KaiserButton                 matlab.ui.control.RadioButton
        HanningButton                matlab.ui.control.RadioButton
        HammingButton                matlab.ui.control.RadioButton
        FileEditField                matlab.ui.control.EditField
        FileEditFieldLabel           matlab.ui.control.Label
        CenterPanel                  matlab.ui.container.Panel
        VISUALIZATIONLabel           matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        FrequencySelectionPanel      matlab.ui.container.Panel
        FsampSlider                  matlab.ui.control.Slider
        FsampSliderLabel             matlab.ui.control.Label
        FstopSlider                  matlab.ui.control.Slider
        FstopSliderLabel             matlab.ui.control.Label
        FpassSlider                  matlab.ui.control.Slider
        FpassSliderLabel             matlab.ui.control.Label
        Hyperlink                    matlab.ui.control.Hyperlink
        PlayPanel                    matlab.ui.container.Panel
        FilteredButton               matlab.ui.control.Button
        OriginalButton               matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        filename % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
             value = app.BrowseButton.ButtonPushedFcn;
            [app.filename,path]=uigetfile('*.wav')
            app.FileEditField.Value=app.filename;
             figure(app.UIFigure);
        end

        % Button pushed function: FilteredButton
        function FilteredButtonPushed(app, event)
           
             %pkg load audio;
            %pkg load signal;
             info = audioinfo(app.filename);
             [y, Fs] = audioread(app.filename);
             Ts = 1/Fs;
             t= 0:1/Fs:30;
             t = t(1:end-1);
             nfft = length(y);
             nfft2 = 2.^nextpow2(nfft);
             fy = fft(y, nfft2);
             fy = fy(1:nfft2/2);
             xfft = Fs*(0:nfft2/2-1)/nfft2;
             %sound(y, Fs);
             plot(app.UIAxes,xfft,abs(fy));
             
fpass = app.FpassSlider.Value;
fstop = app.FstopSlider.Value;
fs = app.FsampSlider.Value;
wp = 2*fpass/Fs;
ws = 2*fstop/Fs;
rp = 0.05;
rs = 0.04;
order = 50;

num = -20*log(sqrt(rp * rs)) - 13;
den = 2.324 * (fstop-fpass)/fs;
n = ceil(num/den);
n1 = n+1;

if(rem(n,2)~=0)
n1 = n;
n = n-1;
end
if app.HammingButton.Value
    z = hamming(n1);
elseif app.HanningButton.Value
    z = hanning(n1);
elseif app.KaiserButton.Value
    z=kaiser(n1);
end
%----------Low Pass Filter----------#
h_low = fir1(n, wp, z);
[h, w] = freqz(h_low, 1, 256)
%----------High Pass Filter----------#
h_high = fir1(n, wp, 'high');

%----------Band Pass Filter----------#
wn = [wp ws];
h_band = fir1(n, wn, 'bandpass');

%----------Convolution for h_low, h_high, h_band----------#
if app.HighPassButton.Value
    filter = conv(y,h_high);
elseif app.LowPassButton.Value
    filter = conv(y,h_low);
elseif app.BandPassButton.Value
    filter = conv(y,h_band);
end


%sound(con_l, Fs);
%sound(con_h, Fs);
nfft = length(filter);
             nfft2 = 2.^nextpow2(nfft);
             fy = fft(filter, nfft2);
             fy = fy(1:nfft2/2);
             xfft = Fs*(0:nfft2/2-1)/nfft2;
             %sound(y, Fs);
          
             plot(app.UIAxes2,xfft,abs(fy));
sound(filter, Fs);

        end

        % Button pushed function: OriginalButton
        function OriginalButtonPushed(app, event)
             info = audioinfo(app.filename);
             [y, Fs] = audioread(app.filename);
             sound(y,Fs);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {214, '1x', 315};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FileEditFieldLabel = uilabel(app.LeftPanel);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.LeftPanel, 'text');
            app.SelectyourWindowButtonGroup = uibuttongroup(app.LeftPanel);
            app.SelectyourWindowButtonGroup.Title = 'Select your Window';
            app.HammingButton = uiradiobutton(app.SelectyourWindowButtonGroup);
            app.HammingButton.Text = 'Hamming';
            app.HammingButton.Value = true;
            app.HanningButton = uiradiobutton(app.SelectyourWindowButtonGroup);
            app.HanningButton.Text = 'Hanning';
            app.KaiserButton = uiradiobutton(app.SelectyourWindowButtonGroup);
            app.KaiserButton.Text = 'Kaiser';
            app.SelectyourFilterButtonGroup = uibuttongroup(app.LeftPanel);
            app.SelectyourFilterButtonGroup.Title = 'Select your Filter';
            app.LowPassButton = uiradiobutton(app.SelectyourFilterButtonGroup);
            app.LowPassButton.Text = 'Low Pass';
            app.LowPassButton.Value = true;
            app.HighPassButton = uiradiobutton(app.SelectyourFilterButtonGroup);
            app.HighPassButton.Text = 'High Pass';
            app.BandPassButton = uiradiobutton(app.SelectyourFilterButtonGroup);
            app.BandPassButton.Text = 'Band Pass';
            app.BrowseButton = uibutton(app.LeftPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SelectyourwavfileLabel = uilabel(app.LeftPanel);
            app.SelectyourwavfileLabel.Text = 'Select your (.wav) file';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Downloads', 'Screenshot__13_-removebg-preview.png');
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Input')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'autumn')
            app.UIAxes2 = uiaxes(app.CenterPanel);
            title(app.UIAxes2, 'Output')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            colormap(app.UIAxes2, 'spring')
            app.VISUALIZATIONLabel = uilabel(app.CenterPanel);
            app.VISUALIZATIONLabel.Text = 'VISUALIZATION';
            app.RightPanel = uipanel(app.GridLayout);
            app.PlayPanel = uipanel(app.RightPanel);
            app.PlayPanel.Title = 'Play ';
            app.OriginalButton = uibutton(app.PlayPanel, 'push');
            app.OriginalButton.ButtonPushedFcn = createCallbackFcn(app, @OriginalButtonPushed, true);
            app.OriginalButton.Text = 'Original';
            app.FilteredButton = uibutton(app.PlayPanel, 'push');
            app.FilteredButton.ButtonPushedFcn = createCallbackFcn(app, @FilteredButtonPushed, true);
            app.FilteredButton.Text = 'Filtered';
            app.Hyperlink = uihyperlink(app.RightPanel);
            app.Hyperlink.URL = 'C:\Users\abhis\OneDrive\Desktop\dsp_project_report.docx';
            app.Hyperlink.Text = 'Report';
            app.FrequencySelectionPanel = uipanel(app.RightPanel);
            app.FrequencySelectionPanel.Title = 'Frequency Selection';
            app.FpassSliderLabel = uilabel(app.FrequencySelectionPanel);
            app.FpassSliderLabel.Text = 'Fpass';
            app.FpassSlider = uislider(app.FrequencySelectionPanel);
            app.FpassSlider.Limits = [0 2000];
            app.FstopSliderLabel = uilabel(app.FrequencySelectionPanel);
            app.FstopSliderLabel.Text = 'Fstop';
            app.FstopSlider = uislider(app.FrequencySelectionPanel);
            app.FstopSlider.Limits = [0 2000];
            app.FsampSliderLabel = uilabel(app.FrequencySelectionPanel);
            app.FsampSliderLabel.Text = 'Fsamp';
            app.FsampSlider = uislider(app.FrequencySelectionPanel);
            app.FsampSlider.Limits = [1000 10000];
            app.FsampSlider.Value = 1000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end